classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GammaSlider       matlab.ui.control.Slider
        GammaSliderLabel  matlab.ui.control.Label
        BetaSlider        matlab.ui.control.Slider
        BetaSliderLabel   matlab.ui.control.Label
        AlphaSlider       matlab.ui.control.Slider
        AlphaSliderLabel  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Vértices originales del cubo
        V_original = [ -0.5 -0.5  0.5;
                       -0.5  0.5  0.5;
                       0.5  0.5  0.5;
                       0.5 -0.5  0.5;
                      -0.5 -0.5 -0.5;
                      -0.5  0.5 -0.5;
                       0.5  0.5 -0.5;
                       0.5 -0.5 -0.5];
        F = [ 1 2 3 4;
              5 6 7 8;
              1 5 8 4;
              2 6 7 3;
              1 5 6 2;
              4 8 7 3];
    
        % Handles para los objetos de la gráfica
        h_cube
        h_ijk_axes
        h_xyz_axes
    end
    
    methods (Access = private)
        
        % Code that executes after app creation
        function startupFcn(app)
            % Configurar el Axes para 3D y la vista inicial
            view(app.UIAxes, 140, 15);
            camproj(app.UIAxes, 'orthographic');
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [-1.5, 1.5]);
            ylim(app.UIAxes, [-1.5, 1.5]);
            zlim(app.UIAxes, [-1.5, 1.5]);
            xlabel(app.UIAxes, 'X'); ylabel(app.UIAxes, 'Y'); zlabel(app.UIAxes, 'Z');
            hold(app.UIAxes, 'on');
    
            % Dibujar el cubo inicial
            app.h_cube = patch(app.UIAxes, 'Vertices', app.V_original, 'Faces', app.F, ...
                               'FaceColor', [0.8 0.8 0.8], 'FaceAlpha', 0.9, ...
                               'EdgeColor', 'k', 'FaceLighting', 'gouraud');
                               
            % Dibujar los ejes de referencia XYZ (sin rotar)
            app.h_xyz_axes(1) = plot3(app.UIAxes, [0 1.5], [0 0], [0 0], 'r', 'LineWidth', 2);
            app.h_xyz_axes(2) = plot3(app.UIAxes, [0 0], [0 1.5], [0 0], 'g', 'LineWidth', 2);
            app.h_xyz_axes(3) = plot3(app.UIAxes, [0 0], [0 0], [0 1.5], 'b', 'LineWidth', 2);

            % Dibujar los ejes IJK rotados (inicialmente alineados con XYZ)
            app.h_ijk_axes(1) = plot3(app.UIAxes, [0 1.3], [0 0], [0 0], 'r--', 'LineWidth', 1);
            app.h_ijk_axes(2) = plot3(app.UIAxes, [0 0], [0 1.3], [0 0], 'g--', 'LineWidth', 1);
            app.h_ijk_axes(3) = plot3(app.UIAxes, [0 0], [0 0], [0 1.3], 'b--', 'LineWidth', 1);
    
            % Añadir la fuente de luz
            light(app.UIAxes, 'Position', [1 1 1], 'Style', 'local');
            lighting(app.UIAxes, 'phong');
        end
        
        % Llama a esta función para actualizar el plot
        function updatePlot(app)
            % Convertir ángulos a radianes
            alpha_rad = deg2rad(app.AlphaSlider.Value);
            beta_rad = deg2rad(app.BetaSlider.Value);
            gamma_rad = deg2rad(app.GammaSlider.Value);

            % Matrices de rotación individuales
            Rx = [1, 0, 0; 0, cos(alpha_rad), -sin(alpha_rad); 0, sin(alpha_rad), cos(alpha_rad)];
            Ry = [cos(beta_rad), 0, sin(beta_rad); 0, 1, 0; -sin(beta_rad), 0, cos(beta_rad)];
            Rz = [cos(gamma_rad), -sin(gamma_rad), 0; sin(gamma_rad), cos(gamma_rad), 0; 0, 0, 1];
            
            % Matriz de rotación compuesta (ZYX)
            R = Rz * Ry * Rx;
            
            % Rotar los vértices del cubo
            V_rot = (R * app.V_original')';
            
            % Actualizar los vértices del cubo (manejador "h_cube")
            set(app.h_cube, 'Vertices', V_rot);

            % Rotar los vectores unitarios para obtener los ejes IJK
            I_axis = R * [1 0 0]';
            J_axis = R * [0 1 0]';
            K_axis = R * [0 0 1]';
            
            % Actualizar las coordenadas de los ejes IJK
            set(app.h_ijk_axes(1), 'XData', [0 I_axis(1)*1.3], 'YData', [0 I_axis(2)*1.3], 'ZData', [0 I_axis(3)*1.3]);
            set(app.h_ijk_axes(2), 'XData', [0 J_axis(1)*1.3], 'YData', [0 J_axis(2)*1.3], 'ZData', [0 J_axis(3)*1.3]);
            set(app.h_ijk_axes(3), 'XData', [0 K_axis(1)*1.3], 'YData', [0 K_axis(2)*1.3], 'ZData', [0 K_axis(3)*1.3]);
        end

        % Callbacks de los Sliders
        function AlphaSliderValueChanged(app, event)
            app.updatePlot();
        end

        function BetaSliderValueChanged(app, event)
            app.updatePlot();
        end

        function GammaSliderValueChanged(app, event)
            app.updatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.BetaSliderLabel = uilabel(app.UIFigure);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.UIFigure);
            app.GammaSliderLabel = uilabel(app.UIFigure);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end